classdef get_color < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        getcolorUIFigure  matlab.ui.Figure
        Menu_1            matlab.ui.container.Menu
        Menu_1_1          matlab.ui.container.Menu
        Menu_2            matlab.ui.container.Menu
        Menu_2_1          matlab.ui.container.Menu
        Menu_2_2          matlab.ui.container.Menu
        Menu_2_3          matlab.ui.container.Menu
        Menu_2_4          matlab.ui.container.Menu
        Menu_2_5          matlab.ui.container.Menu
        Menu_2_5_1        matlab.ui.container.Menu
        Menu_2_5_2        matlab.ui.container.Menu
        PicAxes           matlab.ui.control.UIAxes
        TitleLabel        matlab.ui.control.Label
        ColorLabel        matlab.ui.control.Label
        TextLabel         matlab.ui.control.Label
        savecolorButton   matlab.ui.control.Button
        deletedataButton  matlab.ui.control.Button
        deletepicButton   matlab.ui.control.Button
        getcapButton      matlab.ui.control.Button
        getpicButton      matlab.ui.control.Button
        getcolorButton    matlab.ui.control.Button
        clearlastButton   matlab.ui.control.Button
        Button_L          matlab.ui.control.Button
        Button_N          matlab.ui.control.Button
        pageLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        colorDispClass
        colorClass
        ImageClass
    end  


    methods (Access = private)
        
        function string=ten2sixteen(~,num)
            %the num should be a 1x3 Integer mat limited in [0 255]
            exchange_list={'0','1','2','3','4','5','6','7','8','9','A','B','C','D','E','F'};
            string='#';
            for ii=1:3
                temp_num=num(ii);
                string(1+ii*2-1)=exchange_list{(temp_num-mod(temp_num,16))/16+1};
                string(1+ii*2)=exchange_list{mod(temp_num,16)+1};
            end
        end
        
        function string=colorStr(app,num,type)
            switch type
                case 1
                    tempColorR=sprintf('%.2f',num(1)./255);
                    tempColorG=sprintf('%.2f',num(2)./255);
                    tempColorB=sprintf('%.2f',num(3)./255);
                    string=[tempColorR,'  ',tempColorG,'  ',tempColorB];
                case 2
                    string=[num2str(num(1)),'  ',...
                            num2str(num(2)),'  ',...
                            num2str(num(3))];
                case 3,string=ten2sixteen(app,num); 
                case 4
                    [h,s,v]=rgb2hsv(num(1),num(2),num(3));
                    string=[sprintf('%.3f',h),'  ',...
                            sprintf('%.3f',s),'  ',...
                            num2str(v)];
            end
        end
        function setColor(app)
            startNum=(app.colorClass.tempPage-1)*9;
            for i=1:9
                if startNum+i<app.colorClass.num
                    tempColor=app.colorClass.colorList(startNum+i,:);
                    app.colorDispClass(i).CL.BackgroundColor=tempColor./255;
                    app.colorDispClass(i).TL.Text=app.colorStr(tempColor,app.colorClass.type);
                else
                    app.colorDispClass(i).CL.BackgroundColor=[1 1 1];
                    app.colorDispClass(i).TL.Text='';
                end
            end
        end
        
        function setPage(app)
            app.colorClass.totalPage=ceil((app.colorClass.num-1)/9);
            app.colorClass.totalPage(app.colorClass.totalPage<1)=1;
            app.colorClass.totalPage=app.colorClass.totalPage+1;
            app.pageLabel.Text=[num2str(app.colorClass.tempPage),'/',num2str(app.colorClass.totalPage)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for i=1:9
                app.colorDispClass(i).CL=uilabel(app.getcolorUIFigure);
                app.colorDispClass(i).CL.Position=[600 440-39*(i-1) 30 30];
                app.colorDispClass(i).CL.BackgroundColor=[1 1 1];
                app.colorDispClass(i).CL.Text='';
                
                app.colorDispClass(i).TL=uilabel(app.getcolorUIFigure);
                app.colorDispClass(i).TL.Position=[640 440-39*(i-1) 150 30];
                app.colorDispClass(i).TL.BackgroundColor=[1 1 1];
                app.colorDispClass(i).TL.Text='';
                app.colorDispClass(i).TL.FontName='Cambria';
                app.colorDispClass(i).TL.FontSize=15;
                app.colorDispClass(i).TL.HorizontalAlignment = 'center';
            end
            app.PicAxes.Toolbar.Visible='off';
            app.colorClass.selectedColor=[]; %选中的颜色
            app.colorClass.totalPage=2;      %颜色存储页面总数   
            app.colorClass.tempPage=1;       %当前颜色存储页面
            app.colorClass.colorList=[];     %颜色存储表
            app.colorClass.tempColorList=[]; %当前展示颜色表
            app.colorClass.num=1;            %颜色数量+1
            app.colorClass.type=1;
            
            app.ImageClass.ima=[];         %图片矩阵
            app.ImageClass.XLim=0;         %AXES X轴范围
            app.ImageClass.YLim=0;         %AXES Y轴范围
            app.ImageClass.Selecting=0;    %是否开始取色
        end

        % Window button motion function: getcolorUIFigure
        function WindowButtonMotion(app, event)
            if app.ImageClass.Selecting==1
                xy=app.getcolorUIFigure.CurrentPoint;
                x=xy(1);y=xy(2);
                if x>=45&&x<=450&&y>=30&&y<=435
                    xyInAxes=app.PicAxes.CurrentPoint;
                    x=xyInAxes(1,2);y=xyInAxes(1,1);
                    if x>=app.ImageClass.XLim||y>=app.ImageClass.YLim
                        color=[242,242,242];
                    else
                        x(x<1)=1;y(y<1)=1;
                        x=round(x);y=round(y);
                        if ~isempty(app.ImageClass.ima)
                            color=double([app.ImageClass.ima(x,y,1),...
                               app.ImageClass.ima(x,y,2),...
                               app.ImageClass.ima(x,y,3)]);
                        
                        end
                    end
                    app.TextLabel.Text=app.colorStr(color,app.colorClass.type);
                    app.ColorLabel.BackgroundColor=color./255;
                end   
            end
        end

        % Window button down function: getcolorUIFigure
        function WindowButtonDown(app, event)
            if app.ImageClass.Selecting==1
                xy=app.getcolorUIFigure.CurrentPoint;
                x=xy(1);y=xy(2);
                if x>=45&&x<=450&&y>=30&&y<=435
                    xyInAxes=app.PicAxes.CurrentPoint;
                    x=xyInAxes(1,2);y=xyInAxes(1,1);
                    if x>=app.ImageClass.XLim||y>=app.ImageClass.YLim
                        color=[242,242,242];
                    else
                        x(x<1)=1;y(y<1)=1;
                        x=round(x);y=round(y);
                        if ~isempty(app.ImageClass.ima)
                            color=double([app.ImageClass.ima(x,y,1),...
                               app.ImageClass.ima(x,y,2),...
                               app.ImageClass.ima(x,y,3)]);
                        
                        end
                    end
                    app.ImageClass.Selecting=0;
                    app.TextLabel.Text=app.colorStr(color,app.colorClass.type);
                    app.ColorLabel.BackgroundColor=color./255;
                    app.colorClass.selectedColor=color;
                end  
            end
        end

        % Button pushed function: savecolorButton
        function savecolor(app, event)
            if app.ImageClass.Selecting==0&&~isempty(app.colorClass.selectedColor)
                app.colorClass.colorList(app.colorClass.num,:)=app.colorClass.selectedColor;
                app.colorClass.num=app.colorClass.num+1;
                app.colorClass.tempPage=ceil((app.colorClass.num-1)/9);
                app.colorClass.tempPage(app.colorClass.tempPage==0)=app.colorClass.tempPage;
                app.setColor()
                app.setPage()
            end
        end

        % Button pushed function: deletedataButton
        function deletedata(app, event)
            app.ImageClass.Selecting=0;
            app.ImageClass.ima=[];
            hold(app.PicAxes,'off')
            app.ImageClass.XLim=0;
            app.ImageClass.YLim=0;
            app.PicAxes.XLim=[0,500];
            app.PicAxes.YLim=[0,500];
            imshow(uint8(255.*ones(500,500,3)),'Parent',app.PicAxes)
            app.TextLabel.Text='';
            app.ColorLabel.BackgroundColor=[1 1 1];
        end

        % Button pushed function: deletepicButton
        function deletepic(app, event)
            app.ImageClass.Selecting=0;
            app.ImageClass.ima=[];
            hold(app.PicAxes,'off')
            app.ImageClass.XLim=0;
            app.ImageClass.YLim=0;
            app.PicAxes.XLim=[0,500];
            app.PicAxes.YLim=[0,500];
            imshow(uint8(255.*ones(500,500,3)),'Parent',app.PicAxes)
        end

        % Button pushed function: getcapButton
        function getcap(app, event)
            screensize=get(0,'screensize');
            screensize=1.5*screensize;
            robot=java.awt.Robot();
            rectangle=java.awt.Rectangle();
            rectangle.x=0;
            rectangle.y=0;
            rectangle.width=screensize(3);
            rectangle.height=screensize(4);
            image=robot.createScreenCapture(rectangle);
            data=image.getData();
            temp=zeros(screensize(3)*screensize(4)*3,1);
            temp=data.getPixels(0,0,screensize(3),screensize(4),temp);
            temp=uint8(temp);
            R=temp(1:3:end);
            G=temp(2:3:end);
            B=temp(3:3:end);
            R=reshape(R,[screensize(3),screensize(4)]);
            G=reshape(G,[screensize(3),screensize(4)]);
            B=reshape(B,[screensize(3),screensize(4)]);
            R=R';
            G=G';
            B=B';
            app.ImageClass.XLim=screensize(4); 
            app.ImageClass.YLim=screensize(3); 
            len=max([app.ImageClass.XLim,app.ImageClass.YLim]);
            app.PicAxes.XLim=[0 len];
            app.PicAxes.YLim=[0 len];
            app.ImageClass.ima=cat(3,R,G,B);
            hold(app.PicAxes,'off')
            imshow(app.ImageClass.ima,'Parent',app.PicAxes)
        end

        % Button pushed function: getpicButton
        function getpic(app, event)

            try
                [filename, pathname] = uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
                '*.*','All Files' });
                app.ImageClass.ima=imread([pathname,filename]);
                [app.ImageClass.XLim,app.ImageClass.YLim,~]=size(app.ImageClass.ima);
                len=max([app.ImageClass.XLim,app.ImageClass.YLim]);
                app.PicAxes.XLim=[0 len];
                app.PicAxes.YLim=[0 len];
                hold(app.PicAxes,'off')
                imshow(app.ImageClass.ima,'Parent',app.PicAxes)
            catch
            end
        end

        % Button pushed function: getcolorButton
        function getcolor(app, event)
            if ~isempty(app.ImageClass.ima)
                app.ImageClass.Selecting=1;
            end
        end

        % Button pushed function: clearlastButton
        function clearlast(app, event)
            app.colorClass.num=app.colorClass.num-1;
            app.colorClass.num(app.colorClass.num<1)=1;
            if app.colorClass.num==1
                app.colorClass.colorList=[];
            else
                app.colorClass.colorList=app.colorClass.colorList(1:app.colorClass.num-1,:); 
            end
            app.colorClass.tempPage=ceil((app.colorClass.num-1)/9);
            app.colorClass.tempPage(app.colorClass.tempPage<1)=1;
            app.setColor()
            app.setPage()
        end

        % Menu selected function: Menu_1_1
        function typeSet(app, event)
            typeSetFig=uifigure(); 
            typeSetFig.Name='settype';
            typeSetFig.Color=[0.949 0.949 0.949];
            typeSetFig.Position=[app.getcolorUIFigure.Position(1),...
                                app.getcolorUIFigure.Position(2)+app.getcolorUIFigure.Position(4)-220,...
                                180 190];
            btgroup=uibuttongroup(typeSetFig);
            btgroup.Position=[10 50 160 130];
            btgroup.Title=[];
            
            Button1=uiradiobutton(btgroup);
            Button1.Text='RGB (0-1)';
            Button1.Position=[10 100 140 20]; 
            Button1.FontName='Cambria';
            Button1.FontSize=14;
            
            Button2=uiradiobutton(btgroup);
            Button2.Text='RGB (0-255)';
            Button2.Position=[10 70 140 20];
            Button2.FontName='Cambria';
            Button2.FontSize=14;
            
            Button3=uiradiobutton(btgroup);
            Button3.Text='#16:#XXXXXX';
            Button3.Position=[10 40 140 20];
            Button3.FontName='Cambria';
            Button3.FontSize=14;
            
            Button4=uiradiobutton(btgroup);
            Button4.Text='HSV';
            Button4.Position=[10 10 140 20];
            Button4.FontName='Cambria';
            Button4.FontSize=14;
            
            switch app.colorClass.type
                case 1,Button1.Value=true;
                case 2,Button2.Value=true;
                case 3,Button3.Value=true;
                case 4,Button4.Value=true;
            end
            
            ensureBt=uibutton(typeSetFig, 'push');
            set(ensureBt,'ButtonPushedFcn',@ensure);
            ensureBt.BackgroundColor=[0.8 0.902 0.902];
            ensureBt.FontName='宋体';
            ensureBt.FontSize=18;
            ensureBt.Position=[10 10 75 30];
            ensureBt.Text='确定';
            
            ensureBt=uibutton(typeSetFig, 'push');
            set(ensureBt,'ButtonPushedFcn',@cancel);
            ensureBt.BackgroundColor=[0.8 0.902 0.902];
            ensureBt.FontName='宋体';
            ensureBt.FontSize=18;
            ensureBt.Position=[95 10 75 30];
            ensureBt.Text='取消';
            
            function ensure(~,~)
                selectedBt=btgroup.SelectedObject.Text;
                switch selectedBt
                    case Button1.Text,app.colorClass.type=1;
                    case Button2.Text,app.colorClass.type=2;
                    case Button3.Text,app.colorClass.type=3;
                    case Button4.Text,app.colorClass.type=4;
                end
                if ~isempty(app.colorClass.selectedColor)
                    app.TextLabel.Text=app.colorStr(app.colorClass.selectedColor,app.colorClass.type);
                    app.setColor();
                end
                delete(typeSetFig)         
            end
            function cancel(~,~)
                delete(typeSetFig) 
            end
        end

        % Button pushed function: Button_L
        function to_LastPage(app, event)
            app.colorClass.tempPage=app.colorClass.tempPage-1;
            app.colorClass.tempPage(app.colorClass.tempPage<1)=1;
            app.setColor()
            app.setPage()
        end

        % Button pushed function: Button_N
        function to_NastPage(app, event)
            app.colorClass.tempPage=app.colorClass.tempPage+1;
            app.colorClass.tempPage(app.colorClass.tempPage>app.colorClass.totalPage)=app.colorClass.totalPage;
            app.setColor()
            app.setPage()
        end

        % Menu selected function: Menu_2_1, Menu_2_2, Menu_2_3, 
        % Menu_2_4, Menu_2_5_1, Menu_2_5_2
        function outputData(app, event)
            if app.colorClass.type==3
                tempData(app.colorClass.num-1,1)={''};
            else
                tempData=[];
            end        
            for i=1:(app.colorClass.num-1)
                switch app.colorClass.type
                    case 1
                        tempData(i,:)=roundn(app.colorClass.colorList(i,:)./255,-2);
                    case 2
                        tempData(i,:)=app.colorClass.colorList(i,:);
                    case 3
                        tempData(i,1)={ten2sixteen(app,app.colorClass.colorList(i,:))};
                    case 4   
                        [h,s,v]=rgb2hsv(app.colorClass.colorList(i,1),app.colorClass.colorList(i,2),app.colorClass.colorList(i,3));
                        tempData(i,:)=[h,s,v];
                end
            end
            switch event.Source.Text
                case '命令行导出'
                    disp(['output time:',datestr(now)])
                    disp('color list:')
                    disp(tempData)
                case '存储为mat'   
                    try
                    [filename, pathname] = uiputfile({'*.mat','mat'});
                    color=tempData;
                    save([pathname,filename],'color');
                    catch
                    end
                case '存储为txt'
                    try
                    [filename, pathname] = uiputfile({'*.txt','记事本'});
                    fid=fopen([ pathname,filename],'w');
                    for i=1:app.colorClass.num-1
                        tempColor=app.colorClass.colorList(i,:);
                        fprintf(fid','%s\r\n',app.colorStr(tempColor,app.colorClass.type));
                    end
                    fclose(fid);
                    catch
                    end
                case '存储为excel'
                    [filename, pathname]=uiputfile({'*.xlsx','excel'});
                    if app.colorClass.type==3
                        writecell(tempData,[pathname,filename]) 
                    else
                        writematrix(tempData,[pathname,filename]) 
                    end
                case '当前页码'
                    try
                        m=app.colorClass.num-1;
                        m=m-(app.colorClass.tempPage-1)*9;
                        m(m>9)=9;
                        px=50;
                        pic=ones(9*px,1*px,3);
                        if m>0
                            for ii=(app.colorClass.tempPage-1)*9+1:(app.colorClass.tempPage-1)*9+m
                                for kk=1:3
                                    pic((ii-1)*px+1:ii*px,1:px,kk)=app.colorClass.colorList(ii,kk)/255;
                                end
                            end
                        else
                        end
                        [filename, pathname] = uiputfile({'*.jpg','JPG';'*.png','PNG';'*.','All Image Files'});
                        imwrite(pic,[pathname,filename]);
                    catch
                    end
                case '全部页码'
                    try 
                        page_with_color=app.colorClass.totalPage-1;
                        px=50;
                        gap_px=10;
                        pic=ones(9*px,page_with_color*px+(page_with_color-1)*gap_px,3);
                        for p=1:page_with_color
                            for ii=(p-1)*9+1:p*9
                                for kk=1:3
                                    if ii<=app.colorClass.num-1
                                        pic((ii-(p-1)*9-1)*px+1:(ii-(p-1)*9)*px,(p-1)*(px+gap_px)+1:(p-1)*(px+gap_px)+px,kk)=app.colorClass.colorList(ii,kk)/255;
                                    end
                                end
                            end
                        end
                        [filename, pathname] = uiputfile({'*.jpg;*.png','All Image Files';...
                            '*.jpg','JPG';'*.png','PNG' });
                        imwrite(pic,[pathname,filename]);
                    catch
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.getcolorUIFigure = uifigure('Visible', 'off');
            app.getcolorUIFigure.Name = 'get color';
            app.getcolorUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @WindowButtonDown, true);
            app.getcolorUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @WindowButtonMotion, true);
            app.Menu_1 = uimenu(app.getcolorUIFigure);
            app.Menu_1.Text = '设置';
            app.Menu_1_1 = uimenu(app.Menu_1);
            app.Menu_1_1.MenuSelectedFcn = createCallbackFcn(app, @typeSet, true);
            app.Menu_1_1.Text = '颜色格式';
            app.Menu_2 = uimenu(app.getcolorUIFigure);
            app.Menu_2.Text = '保存';
            app.Menu_2_1 = uimenu(app.Menu_2);
            app.Menu_2_1.MenuSelectedFcn = createCallbackFcn(app, @outputData, true);
            app.Menu_2_1.Text = '命令行导出';
            app.Menu_2_2 = uimenu(app.Menu_2);
            app.Menu_2_2.MenuSelectedFcn = createCallbackFcn(app, @outputData, true);
            app.Menu_2_2.Text = '存储为mat';
            app.Menu_2_3 = uimenu(app.Menu_2);
            app.Menu_2_3.MenuSelectedFcn = createCallbackFcn(app, @outputData, true);
            app.Menu_2_3.Text = '存储为txt';
            app.Menu_2_4 = uimenu(app.Menu_2);
            app.Menu_2_4.MenuSelectedFcn = createCallbackFcn(app, @outputData, true);
            app.Menu_2_4.Text = '存储为excel';
            app.Menu_2_5 = uimenu(app.Menu_2);
            app.Menu_2_5.Text = '存储为颜色图';
            app.Menu_2_5_1 = uimenu(app.Menu_2_5);
            app.Menu_2_5_1.MenuSelectedFcn = createCallbackFcn(app, @outputData, true);
            app.Menu_2_5_1.Text = '当前页码';
            app.Menu_2_5_2 = uimenu(app.Menu_2_5);
            app.Menu_2_5_2.MenuSelectedFcn = createCallbackFcn(app, @outputData, true);
            app.Menu_2_5_2.Text = '全部页码';
            app.PicAxes = uiaxes(app.getcolorUIFigure);
            title(app.PicAxes, '')
            xlabel(app.PicAxes, '')
            ylabel(app.PicAxes, '')
            app.PicAxes.XLim = [0 500];
            app.PicAxes.YLim = [0 500];
            app.PicAxes.XTick = [];
            app.PicAxes.YTick = [];
            app.TitleLabel = uilabel(app.getcolorUIFigure);
            app.TitleLabel.Text = '色彩识别';
            app.ColorLabel = uilabel(app.getcolorUIFigure);
            app.ColorLabel.Text = '';
            app.TextLabel = uilabel(app.getcolorUIFigure);
            app.TextLabel.Text = '';
            app.savecolorButton = uibutton(app.getcolorUIFigure, 'push');
            app.savecolorButton.ButtonPushedFcn = createCallbackFcn(app, @savecolor, true);
            app.savecolorButton.Text = '储存颜色';
            app.deletedataButton = uibutton(app.getcolorUIFigure, 'push');
            app.deletedataButton.ButtonPushedFcn = createCallbackFcn(app, @deletedata, true);
            app.deletedataButton.Text = '清空数据';
            app.deletepicButton = uibutton(app.getcolorUIFigure, 'push');
            app.deletepicButton.ButtonPushedFcn = createCallbackFcn(app, @deletepic, true);
            app.deletepicButton.Text = '删除图片';
            app.getcapButton = uibutton(app.getcolorUIFigure, 'push');
            app.getcapButton.ButtonPushedFcn = createCallbackFcn(app, @getcap, true);
            app.getcapButton.Text = '屏幕截图';
            app.getpicButton = uibutton(app.getcolorUIFigure, 'push');
            app.getpicButton.ButtonPushedFcn = createCallbackFcn(app, @getpic, true);
            app.getpicButton.Text = '读取图片';
            app.getcolorButton = uibutton(app.getcolorUIFigure, 'push');
            app.getcolorButton.ButtonPushedFcn = createCallbackFcn(app, @getcolor, true);
            app.getcolorButton.Text = '获取颜色';
            app.clearlastButton = uibutton(app.getcolorUIFigure, 'push');
            app.clearlastButton.ButtonPushedFcn = createCallbackFcn(app, @clearlast, true);
            app.clearlastButton.Text = '清除最后一个颜色';
            app.Button_L = uibutton(app.getcolorUIFigure, 'push');
            app.Button_L.ButtonPushedFcn = createCallbackFcn(app, @to_LastPage, true);
            app.Button_L.Text = '<上一页';
            app.Button_N = uibutton(app.getcolorUIFigure, 'push');
            app.Button_N.ButtonPushedFcn = createCallbackFcn(app, @to_NastPage, true);
            app.Button_N.Text = '下一页>';
            app.pageLabel = uilabel(app.getcolorUIFigure);
            app.pageLabel.Text = '1/2';
            % Show the figure after all components are created
            app.getcolorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = get_color

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.getcolorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.getcolorUIFigure)
        end
    end
end