classdef matex < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATEXUIFigure  matlab.ui.Figure
        Menu_1         matlab.ui.container.Menu
        Menu_1_1       matlab.ui.container.Menu
        Menu_1_2       matlab.ui.container.Menu
        Menu_1_3       matlab.ui.container.Menu
        Menu_1_4       matlab.ui.container.Menu
        Menu_1_5       matlab.ui.container.Menu
        outputPanel    matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
        TabGroup       matlab.ui.container.TabGroup
        Tab_1          matlab.ui.container.Tab
        Panel_1_3      matlab.ui.container.Panel
        Panel_1_2      matlab.ui.container.Panel
        Panel_1_1      matlab.ui.container.Panel
        ButtonGroup    matlab.ui.container.ButtonGroup
        XLabel_X       matlab.ui.control.Label
        EditField_C    matlab.ui.control.NumericEditField
        EditField_R    matlab.ui.control.NumericEditField
        Button_Array   matlab.ui.control.Button
        Tab_2          matlab.ui.container.Tab
        Panel_2_4      matlab.ui.container.Panel
        Panel_2_3      matlab.ui.container.Panel
        Panel_2_2      matlab.ui.container.Panel
        Panel_2_1      matlab.ui.container.Panel
        Tab_3          matlab.ui.container.Tab
        Panel_3_3      matlab.ui.container.Panel
        Panel_3_2      matlab.ui.container.Panel
        Panel_3_1      matlab.ui.container.Panel
        Tab_4          matlab.ui.container.Tab
        Panel_4_3      matlab.ui.container.Panel
        Panel_4_2      matlab.ui.container.Panel
        Panel_4_1      matlab.ui.container.Panel
        Tab_5          matlab.ui.container.Tab
        Panel_5_2      matlab.ui.container.Panel
        Panel_5_1      matlab.ui.container.Panel
        Tab_6          matlab.ui.container.Tab
        Panel_6_1      matlab.ui.container.Panel
        Panel_6_3      matlab.ui.container.Panel
        Panel_6_2      matlab.ui.container.Panel
        Panel_4_4      matlab.ui.container.Panel
        Tab_7          matlab.ui.container.Tab
        Panel_7_2      matlab.ui.container.Panel
        Panel_7_1      matlab.ui.container.Panel
        Slider_C       matlab.ui.control.Slider
        Slider_R       matlab.ui.control.Slider
        TextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        latexHdl;
        btns_1_1; % Panel_1_1 : ButtonGroup : array
        btns_1_2; % Panel_1_2 : brackets
        btns_1_3; % Panel_1_3 : commons

        btns_2_1; % Panel_2_1 : sum
        btns_2_2; % Panel_2_2 : prod
        btns_2_3; % Panel_2_3 : cc
        btns_2_4; % Panel_2_4 : vvv

        btns_3_1; % Panel_3_1 : int
        btns_3_2; % Panel_3_2 : frac
        btns_3_3; % Panel_3_3 : diff

        btns_4_1; % Panel_4_1 : sqrt
        btns_4_2; % Panel_4_2 : ss
        btns_4_3; % Panel_4_3 : ao

        btns_5_1; % Panel_5_1 : B1
        btns_5_2; % Panel_5_2 : B2

        btns_6_1; % Panel_6_1 : G1
        btns_6_2; % Panel_6_2 : G2
        btns_6_3; % Panel_6_3 : G3

        btns_7_1; % Panel_7_1 : S1
        btns_7_2; % Panel_7_2 : S2
    end
    
    methods (Access = private)
        
        function createBrackets_Str_Fcn(app,event)
            % 括号生成函数
            BList={'\left(  \right)';'\left[  \right]';'\left\langle  \right\rangle';
                '\left\{  \right\}';'\left|  \right|';'\left\|  \right\|';
                '\left\lfloor  \right\rfloor';'\left\lceil  \right\rceil'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},BList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createCommons_Str_Fcn(app,event)
            % 其他常用括号生成函数
            CList={'\left[0,1\right)';'\left.\langle\psi \right|';
                '\left| \psi \right\rangle';'\left\langle \psi | \psi \right\rangle'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},CList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createSum_Str_Fcn(app,event)
            % 加法生成函数
            SList={'\sum';'\sum_{}^{}';'{\textstyle \sum_{}^{}}'};
            if ~isempty(app.TextArea.Value{1})
                app.TextArea.Value=[app.TextArea.Value;SList{event.Source.UserData}];
            else
                app.TextArea.Value=SList{event.Source.UserData};
            end
            TextAreaValueChanged(app)
        end
        function createProd_Str_Fcn(app,event)
            % 乘法生成函数
            PList={'\prod';'\prod_{}^{}';'{\textstyle \prod_{}^{}}';
                '\coprod';'\coprod_{}^{}';'{\textstyle \coprod_{}^{}}'};
            if ~isempty(app.TextArea.Value{1})
                app.TextArea.Value=[app.TextArea.Value;PList{event.Source.UserData}];
            else
                app.TextArea.Value=PList{event.Source.UserData};
            end
            TextAreaValueChanged(app)
        end
        function createCupcap_Str_Fcn(app,event)
            % 交并生成函数
            CList={'\bigcup';'\bigcup_{}^{}';'{\textstyle \bigcup_{}^{}}';
                '\bigcap';'\bigcap_{}^{}';'{\textstyle \bigcap_{}^{}}'};
            if ~isempty(app.TextArea.Value{1})
                app.TextArea.Value=[app.TextArea.Value;CList{event.Source.UserData}];
            else
                app.TextArea.Value=CList{event.Source.UserData};
            end
            TextAreaValueChanged(app)
        end
        function createVwee_Str_Fcn(app,event)
            % 析合生成函数
            VList={'\bigvee';'\bigvee_{}^{}';'{\textstyle \bigvee_{}^{}}';
                '\bigwedge';'\bigwedge_{}^{}';'{\textstyle \bigwedge_{}^{}}'};
            if ~isempty(app.TextArea.Value{1})
                app.TextArea.Value=[app.TextArea.Value;VList{event.Source.UserData}];
            else
                app.TextArea.Value=VList{event.Source.UserData};
            end
            TextAreaValueChanged(app)
        end
        function createInt_Str_Fcn(app,event)
            % 积分生成函数
            IList={'\int';'\int_{}^{}';'\int\limits_{}^{}';'\oint';'\oint_{}^{}'};
            if ~isempty(app.TextArea.Value{1})
                app.TextArea.Value=[app.TextArea.Value;IList{event.Source.UserData}];
            else
                app.TextArea.Value=IList{event.Source.UserData};
            end
            TextAreaValueChanged(app)
        end
        function createFrac_Str_Fcn(app,event)
            % 分数生成函数
            FList={'\frac{}{}';'\mathrm{d}t';'\frac{\mathrm{d}y}{\mathrm{d}x}';
                '\partial t';'\frac{\partial y}{\partial x}';'\nabla\psi'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},FList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createDiff_Str_Fcn(app,event)
            % 导数生成函数
            DList={'\dot{a}';'\ddot{a}';['{f}',char(39)];['{f}',char(39),char(39)];'{f}^{(n)}'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},DList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createSqrt_Str_Fcn(app,event)
            % 根式生成函数
            SList={'\sqrt{x}';'\sqrt[n]{x}'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},SList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createSubp_Str_Fcn(app,event)
            % 角标生成函数
            SList={'x^{n}';'x_{n}';'x_{a}^{b}';'\,_{a}^{b}x'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},SList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createAo_Str_Fcn(app,event)
            % AO生成函数
            AList={'\hat{a}';'\check{a}';'\grave{a}';'\acute{a}';'\tilde{a}';'\breve{a}';'\bar{a}';'\vec{a}'; 
                '\widetilde{ab}';'\widehat{ab}';'\overline{ab}';'\overleftarrow{ab}';'\overrightarrow{ab}'; 
                '\overbrace{ab}';'\underbrace{ab}'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},AList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createB1_Str_Fcn(app,event)
            % B1生成函数
            BList={'\times';'\div';'\pm';'\mp';'\triangleleft';'\triangleright';'\cdot';
                '\setminus';'\star';'\ast';'\cup';'\cap';'\sqcup';'\sqcap';'\vee';'\wedge';
                '\circ';'\bullet';'\oplus';'\ominus';'\odot';'\oslash';'\otimes';'\bigcirc';
                '\diamond';'\uplus';'\bigtriangleup';'\bigtriangledown';'\amalg';'\wr';'\dagger';'\ddagger'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},BList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createB2_Str_Fcn(app,event)
            % B2生成函数
            BList={'\le';'\ge';'\equiv';'\ll';'\gg';'\doteq';'\prec';'\succ';'\sim';'\preceq';'\succeq';'\simeq'; 
                '\approx';'\subset';'\supset';'\subseteq';'\supseteq';'\bowtie';'\propto';'\in';'\ni';'\vdash'; 
                '\dashv';'\models';'\mid';'\parallel';'\perp';'\asymp';'\notin';'\ne'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},BList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createG1_Str_Fcn(app,event)
            % G1生成函数
            GList={'\alpha';'\beta';'\gamma';'\delta';'\epsilon';'\varepsilon';'\zeta';
                '\eta';'\theta';'\vartheta';'\iota';'\kappa';'\lambda';'\mu';'\nu';'\xi';
                '\pi';'\varpi';'\rho';'\varrho';'\sigma';'\varsigma';'\tau';'\upsilon';
                '\phi';'\varphi';'\chi';'\psi';'\omega'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},GList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createG2_Str_Fcn(app,event)
            % G2生成函数
            GList={'\Gamma';'\Delta';'\Theta';'\Lambda';'\Xi';'\Pi';'\Sigma';'\Upsilon';'\Phi';'\Psi';'\Omega'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},GList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createG3_Str_Fcn(app,event)
            % G3生成函数
            GList={'\hbar';'\imath';'\jmath';'\ell';'\Re';'\Im';'\aleph';'\wp';'\S'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},GList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createS1_Str_Fcn(app,event)
            % S1生成函数
            SList={'\gets';'\to';'\longleftarrow';'\longrightarrow';'\uparrow';'\downarrow';
                '\updownarrow';'\leftrightarrow';'\Uparrow';'\Downarrow';'\Updownarrow';
                '\longleftrightarrow';'\Leftarrow';'\Longleftarrow';'\Rightarrow';'\Longrightarrow';
                '\Leftrightarrow';'\Longleftrightarrow';'\mapsto';'\longmapsto';'\nearrow';'\searrow';
                '\swarrow';'\nwarrow';'\hookleftarrow';'\hookrightarrow';'\rightleftharpoons';'\iff';
                '\leftharpoonup';'\rightharpoonup';'\leftharpoondown';'\rightharpoondown'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},SList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
        function createS2_Str_Fcn(app,event)
            % S2生成函数
            SList={'\dots';'\cdots';'\vdots';'\ddots';'\forall';'\exists';'\neg';'\prime';
                '\emptyset';'\infty';'\nabla';'\triangle';'\bot';'\top';'\angle';'\surd';
                '\diamondsuit';'\heartsuit';'\clubsuit';'\spadesuit'};
            app.TextArea.Value{end}=[app.TextArea.Value{end},SList{event.Source.UserData}];
            TextAreaValueChanged(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            verInf={'+--------------------------------------------+';
                    '|【Basic Inf】                               |';
                    '| @version : 1.0                             |';
                    '| @author  : slandarer                       |';
                    '| @CSDN    : slandarer                       |';
                    '| @知乎     : hikari                          |';
                    '| @公众号   : slandarer随笔                    |';
                    '| @ver Matlab : R2022a                       |';
                    '+--------------------------------------------+'};
            for i=1:length(verInf),disp(verInf{i}),end 
            
            app.latexHdl=text(app.UIAxes,0.01,0.95,'','FontSize',18,...
                'Interpreter','latex','VerticalAlignment','top');
            % 矩阵生成部分
            for i=1:2
                for j=1:5
                    if (i-1)*5+j<=9
                        app.btns_1_1(i,j)=uitogglebutton(app.ButtonGroup,...
                            'Position',[10+(j-1)*75,245-75*(i+1),65,65],'UserData',(i-1)*5+j,...
                            'Text','','Icon',['matexpic\数组相关\ARRAY_',num2str((i-1)*5+j),'.png']);
                    end
                end
            end
            % 括号部分
            for i=1:1
                for j=1:8
                    if j<=8
                        app.btns_1_2(i,j)=uibutton(app.Panel_1_2,...
                            'Position',[10+(j-1)*46,125-46*(i+1),36,36],'UserData',j,...
                            'Text','','Icon',['matexpic\数组相关\BRACKETS_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createBrackets_Str_Fcn, true));
                    end
                end
            end
            % 其他常用
            for i=1:1
                for j=1:4
                    if j<=4
                        app.btns_1_3(i,j)=uibutton(app.Panel_1_3,...
                            'Position',[10+(j-1)*82,127-46*(i+1),72,36],'UserData',j,...
                            'Text','','Icon',['matexpic\数组相关\COMMONS_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createCommons_Str_Fcn, true));
                    end
                end
            end
            % =============================================================
            % 加法部分
            for i=1:1
                for j=1:3
                    if j<=3
                        app.btns_2_1(i,j)=uibutton(app.Panel_2_1,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\大型运算\SUM_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createSum_Str_Fcn, true));
                    end
                end
            end
            % 乘法部分
            for i=1:1
                for j=1:6
                    if j<=6
                        app.btns_2_2(i,j)=uibutton(app.Panel_2_2,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\大型运算\PROD_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createProd_Str_Fcn, true));
                    end
                end
            end
            % 交并部分
            for i=1:1
                for j=1:6
                    if j<=6
                        app.btns_2_3(i,j)=uibutton(app.Panel_2_3,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\大型运算\CUPCAP_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createCupcap_Str_Fcn, true));
                    end
                end
            end
            % 析合部分
            for i=1:1
                for j=1:6
                    if j<=6
                        app.btns_2_4(i,j)=uibutton(app.Panel_2_4,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\大型运算\VWEE_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createVwee_Str_Fcn, true));
                    end
                end
            end
            % =============================================================
            % 积分部分
            for i=1:1
                for j=1:5
                    if j<=5
                        app.btns_3_1(i,j)=uibutton(app.Panel_3_1,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\积分微分\INT_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createInt_Str_Fcn, true));
                    end
                end
            end
            % 分数部分
            for i=1:1
                for j=1:6
                    if j<=6
                        app.btns_3_2(i,j)=uibutton(app.Panel_3_2,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\积分微分\FRAC_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createFrac_Str_Fcn, true));
                    end
                end
            end
            % 导数部分
            for i=1:1
                for j=1:5
                    if j<=5
                        app.btns_3_3(i,j)=uibutton(app.Panel_3_3,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\积分微分\DIFF_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createDiff_Str_Fcn, true));
                    end
                end
            end
            % =============================================================
            % 根式部分
            for i=1:1
                for j=1:2
                    if j<=2
                        app.btns_4_1(i,j)=uibutton(app.Panel_4_1,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\根式角标\SQRT_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createSqrt_Str_Fcn, true));
                    end
                end
            end
            % 角标部分
            for i=1:1
                for j=1:4
                    if j<=4
                        app.btns_4_2(i,j)=uibutton(app.Panel_4_2,...
                            'Position',[10+(j-1)*60,150-60*(i+1),50,50],'UserData',j,...
                            'Text','','Icon',['matexpic\根式角标\SUBP_',num2str(j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createSubp_Str_Fcn, true));
                    end
                end
            end
            % 角标部分
            for i=1:3
                for j=1:5
                    if (i-1)*5+j<=15
                        app.btns_4_3(i,j)=uibutton(app.Panel_4_3,...
                            'Position',[10+(j-1)*53,250-53*(i+1),43,43],'UserData',(i-1)*5+j,...
                            'Text','','Icon',['matexpic\根式角标\AO_',num2str((i-1)*5+j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createAo_Str_Fcn, true));
                    end
                end
            end
            % =============================================================
            % B1部分
            for i=1:4
                for j=1:8
                    if (i-1)*8+j<=32
                        app.btns_5_1(i,j)=uibutton(app.Panel_5_1,...
                            'Position',[10+(j-1)*45,230-43*(i+1),35,35],'UserData',(i-1)*8+j,...
                            'Text','','Icon',['matexpic\二元符号\B1_',num2str((i-1)*8+j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createB1_Str_Fcn, true));
                    end
                end
            end
            % B2部分
            for i=1:4
                for j=1:8
                    if (i-1)*8+j<=30
                        app.btns_5_2(i,j)=uibutton(app.Panel_5_2,...
                            'Position',[10+(j-1)*45,230-43*(i+1),35,35],'UserData',(i-1)*8+j,...
                            'Text','','Icon',['matexpic\二元符号\B2_',num2str((i-1)*8+j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createB2_Str_Fcn, true));
                    end
                end
            end
            % =============================================================
            % G1部分
            for i=1:3
                for j=1:10
                    if (i-1)*10+j<=29
                        app.btns_6_1(i,j)=uibutton(app.Panel_6_1,...
                            'Position',[10+(j-1)*36,160-36*(i+1),30,30],'UserData',(i-1)*10+j,...
                            'Text','','Icon',['matexpic\希腊字母\G1_',num2str((i-1)*10+j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createG1_Str_Fcn, true));
                    end
                end
            end
            % G2部分
            for i=1:2
                for j=1:6
                    if (i-1)*6+j<=11
                        app.btns_6_2(i,j)=uibutton(app.Panel_6_2,...
                            'Position',[10+(j-1)*36,125-36*(i+1),30,30],'UserData',(i-1)*6+j,...
                            'Text','','Icon',['matexpic\希腊字母\G2_',num2str((i-1)*6+j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createG2_Str_Fcn, true));
                    end
                end
            end
            % G3部分
            for i=1:2
                for j=1:5
                    if (i-1)*5+j<=9
                        app.btns_6_3(i,j)=uibutton(app.Panel_6_3,...
                            'Position',[10+(j-1)*36,125+37-36*(i+1),30,30],'UserData',(i-1)*5+j,...
                            'Text','','Icon',['matexpic\希腊字母\G3_',num2str((i-1)*5+j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createG3_Str_Fcn, true));
                    end
                end
            end
            % S1部分
            for i=1:4
                for j=1:8
                    if (i-1)*8+j<=32
                        app.btns_7_1(i,j)=uibutton(app.Panel_7_1,...
                            'Position',[10+(j-1)*45,230-43*(i+1),35,35],'UserData',(i-1)*8+j,...
                            'Text','','Icon',['matexpic\特殊符号\S1_',num2str((i-1)*8+j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createS1_Str_Fcn, true));
                    end
                end
            end
            % S2部分
            for i=1:3
                for j=1:8
                    if (i-1)*8+j<=20
                        app.btns_7_2(i,j)=uibutton(app.Panel_7_2,...
                            'Position',[10+(j-1)*45,230-43*(i+1),35,35],'UserData',(i-1)*8+j,...
                            'Text','','Icon',['matexpic\特殊符号\S2_',num2str((i-1)*8+j),'.png'],...
                            'ButtonPushedFcn',createCallbackFcn(app, @createS2_Str_Fcn, true));
                    end
                end
            end
        end

        % Menu selected function: Menu_1_1, Menu_1_2, Menu_1_3, Menu_1_4, 
        % ...and 1 other component
        function Menu_Selected(app, event)
            switch event.Source.Text
                case '常规',app.TextArea.Value{end}=[app.TextArea.Value{end},'\mathrm{}'];
                case '加粗',app.TextArea.Value{end}=[app.TextArea.Value{end},'\mathbf{}'];
                case '斜体',app.TextArea.Value{end}=[app.TextArea.Value{end},'\mathit{}'];
                case '下划线',app.TextArea.Value{end}=[app.TextArea.Value{end},'\underline{}'];
                case '手写体',app.TextArea.Value{end}=[app.TextArea.Value{end},'\mathcal{}'];
            end
            TextAreaValueChanged(app)
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value=app.TextArea.Value;
            if isempty(value{1})
                app.latexHdl.String='';
            else
                app.latexHdl.String=['$$',value{:},'$$'];
            end
        end

        % Value changing function: Slider_R
        function Slider_RValueChanging(app, event)
            changingValue=event.Value;
            app.UIAxes.XLim=[changingValue,changingValue+1];
        end

        % Value changing function: Slider_C
        function Slider_CValueChanging(app, event)
            changingValue=event.Value;
            app.UIAxes.YLim=[changingValue-10,changingValue-9];
        end

        % Button pushed function: Button_Array
        function createArray_Str_Fcn(app, event)
            % 矩阵生成函数
            trow=app.EditField_R.Value;
            tcol=app.EditField_C.Value;
            tstr_type=app.ButtonGroup.SelectedObject.UserData;

            tLList={'','\left(','\left[','\left|','\left\|','\left\{','\left\{','\left.'};
            tRList={'','\right)','\right]','\right|','\right\|','\right\}','\right.','\right\}'};
            tccc=['{',char(ones(1,tcol).*abs('c')),'}'];
            tspace=char([32,repmat(abs('& '),[1,tcol-1])]);

            tlatex={};
            if tstr_type==9
                tlatex{1}='\begin{cases}';
                for i=2:(trow)
                    tlatex{i,1}=' \\';
                end
                tlatex{trow+1,1}=' ';
                tlatex{trow+2,1}='\end{cases}';
            else
                tlatex{1}=[tLList{tstr_type},'\begin{array}',tccc];
                for i=2:(trow)
                    tlatex{i,1}=[tspace,'\\'];
                end
                tlatex{trow+1,1}=tspace;
                tlatex{trow+2,1}=['\end{array}',tRList{tstr_type}];
            end
            if ~isempty(app.TextArea.Value{1})
                app.TextArea.Value=[app.TextArea.Value;tlatex];
            else
                app.TextArea.Value=tlatex;
            end
            TextAreaValueChanged(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MATEXUIFigure = uifigure('Visible', 'off');
            app.MATEXUIFigure.Name = 'MATEX';
            app.MATEXUIFigure.Icon = fullfile(pathToMLAPP, 'matexpic', 'icon.png');
            app.Menu_1 = uimenu(app.MATEXUIFigure);
            app.Menu_1.Text = '字体';
            app.Menu_1_1 = uimenu(app.Menu_1);
            app.Menu_1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu_Selected, true);
            app.Menu_1_1.Text = '常规';
            app.Menu_1_2 = uimenu(app.Menu_1);
            app.Menu_1_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_Selected, true);
            app.Menu_1_2.Text = '加粗';
            app.Menu_1_3 = uimenu(app.Menu_1);
            app.Menu_1_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_Selected, true);
            app.Menu_1_3.Text = '斜体';
            app.Menu_1_4 = uimenu(app.Menu_1);
            app.Menu_1_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_Selected, true);
            app.Menu_1_4.Text = '下划线';
            app.Menu_1_5 = uimenu(app.Menu_1);
            app.Menu_1_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_Selected, true);
            app.Menu_1_5.Text = '手写体';
            app.TextArea = uitextarea(app.MATEXUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Placeholder = '在此输入latex公式';
            app.Slider_R = uislider(app.MATEXUIFigure);
            app.Slider_R.Limits = [0 10];
            app.Slider_R.MajorTicks = [];
            app.Slider_R.MajorTickLabels = {''};
            app.Slider_R.ValueChangingFcn = createCallbackFcn(app, @Slider_RValueChanging, true);
            app.Slider_R.MinorTicks = [];
            app.Slider_C = uislider(app.MATEXUIFigure);
            app.Slider_C.Limits = [0 10];
            app.Slider_C.MajorTicks = [];
            app.Slider_C.MajorTickLabels = {''};
            app.Slider_C.ValueChangingFcn = createCallbackFcn(app, @Slider_CValueChanging, true);
            app.Slider_C.MinorTicks = [];
            app.Slider_C.Value = 10;
            app.TabGroup = uitabgroup(app.MATEXUIFigure);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '数组相关';
            app.Panel_1_1 = uipanel(app.Tab_1);
            app.Panel_1_1.Title = '矩阵数组';
            app.ButtonGroup = uibuttongroup(app.Panel_1_1);
            app.Button_Array = uibutton(app.ButtonGroup, 'push');
            app.Button_Array.ButtonPushedFcn = createCallbackFcn(app, @createArray_Str_Fcn, true);
            app.Button_Array.Text = '生 成';
            app.EditField_R = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField_R.Limits = [1 20];
            app.EditField_R.ValueDisplayFormat = '%.0f';
            app.EditField_R.Value = 2;
            app.EditField_C = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField_C.Limits = [1 20];
            app.EditField_C.ValueDisplayFormat = '%.0f';
            app.EditField_C.Value = 3;
            app.XLabel_X = uilabel(app.ButtonGroup);
            app.XLabel_X.Text = 'X';
            app.Panel_1_2 = uipanel(app.Tab_1);
            app.Panel_1_2.Title = '括号 Brackets';
            app.Panel_1_3 = uipanel(app.Tab_1);
            app.Panel_1_3.Title = '常用 Commons';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '大型运算';
            app.Panel_2_1 = uipanel(app.Tab_2);
            app.Panel_2_1.Title = '求和 Summation';
            app.Panel_2_2 = uipanel(app.Tab_2);
            app.Panel_2_2.Title = '乘积余积 Product and coproduct';
            app.Panel_2_3 = uipanel(app.Tab_2);
            app.Panel_2_3.Title = '并集交集 Union and intersection';
            app.Panel_2_4 = uipanel(app.Tab_2);
            app.Panel_2_4.Title = '析取合取 Disjunction and conjunction';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '积分微分';
            app.Panel_3_1 = uipanel(app.Tab_3);
            app.Panel_3_1.Title = '积分 Integral';
            app.Panel_3_2 = uipanel(app.Tab_3);
            app.Panel_3_2.Title = '分数 Fractions';
            app.Panel_3_3 = uipanel(app.Tab_3);
            app.Panel_3_3.Title = '导数 Derivative';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '根式角标';
            app.Panel_4_1 = uipanel(app.Tab_4);
            app.Panel_4_1.Title = '根式 Radicals';
            app.Panel_4_2 = uipanel(app.Tab_4);
            app.Panel_4_2.Title = '上下标 Sub&Super';
            app.Panel_4_3 = uipanel(app.Tab_4);
            app.Panel_4_3.Title = '重音符及其他 Accents and Others';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '二元符号';
            app.Panel_5_1 = uipanel(app.Tab_5);
            app.Panel_5_1.Title = '二元运算符 Binary operations';
            app.Panel_5_2 = uipanel(app.Tab_5);
            app.Panel_5_2.Title = '二元关系符 Binary relationser';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '希腊字母';
            app.Panel_4_4 = uipanel(app.Tab_6);
            app.Panel_4_4.Title = '根式 Radicals';
            app.Panel_6_2 = uipanel(app.Tab_6);
            app.Panel_6_2.Title = '大写 Uppercase';
            app.Panel_6_3 = uipanel(app.Tab_6);
            app.Panel_6_3.Title = '其他 Others';
            app.Panel_6_1 = uipanel(app.Tab_6);
            app.Panel_6_1.Title = '小写 Lowercase';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '特殊符号';
            app.Panel_7_1 = uipanel(app.Tab_7);
            app.Panel_7_1.Title = '箭头符号 Arrows';
            app.Panel_7_2 = uipanel(app.Tab_7);
            app.Panel_7_2.Title = '其他符号 Others';
            app.outputPanel = uipanel(app.MATEXUIFigure);
            app.outputPanel.Title = '   输出区域 (output)';
            app.UIAxes = uiaxes(app.outputPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.MATEXUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matex

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATEXUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATEXUIFigure)
        end
    end
end